classdef SolderingIronApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConnectionPanel          matlab.ui.container.Panel
        BaudDropDownLabel        matlab.ui.control.Label
        BaudDropDown             matlab.ui.control.DropDown
        ConnectButton            matlab.ui.control.Button
        PortDropDownLabel        matlab.ui.control.Label
        PortDropDown             matlab.ui.control.DropDown
        UIAxes1                  matlab.ui.control.UIAxes
        PIDtunePanel             matlab.ui.container.Panel
        SendButton               matlab.ui.control.Button
        KpEditField_2Label       matlab.ui.control.Label
        KpEditField              matlab.ui.control.NumericEditField
        KiEditFieldLabel         matlab.ui.control.Label
        KiEditField              matlab.ui.control.NumericEditField
        KdEditFieldLabel         matlab.ui.control.Label
        KdEditField              matlab.ui.control.NumericEditField
        UIAxes2                  matlab.ui.control.UIAxes
        DataLoggingPanel         matlab.ui.container.Panel
        SamplesDropDownLabel     matlab.ui.control.Label
        SamplesDropDown          matlab.ui.control.DropDown
        MessageBoxTextAreaLabel  matlab.ui.control.Label
        MessageBoxTextArea       matlab.ui.control.TextArea
        StartCaptureButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        COMPortName % Description
        COMBaudRate
        COMSerialPort
        UARTData
        Plot1Data
        Plot2Data
        FirstTimeData
        FirstData
        StartFrame
    end
    
    methods (Access = private)
        
        function UARTReceiveCallback(app, src, event)
            str=fscanf(app.COMSerialPort);
            Record=strsplit(str,';');
            [DataRow,DataCol]=size(app.UARTData);
            RecordDouble=str2double(Record);
            if app.FirstData==1
                app.FirstTimeData=RecordDouble(2);
                app.Plot1Data=zeros(1000,8);
                x1=transpose([-10:0.01:-0.01]);
                app.Plot1Data(:,2)=x1;
                app.FirstData=0;
            end
            app.UARTData=RecordDouble;
            app.UARTData(1,2)=(RecordDouble(1,2)-app.FirstTimeData)/1000;
            app.Plot1Data=[app.Plot1Data(2:1000,:); app.UARTData];
            %
            app.UIAxes1.XLim=[min(app.Plot1Data(:,2)) max(app.Plot1Data(:,2))];
            plot(app.UIAxes1,app.Plot1Data(:,2),app.Plot1Data(:,4),app.Plot1Data(:,2),app.Plot1Data(:,5),'LineWidth',1.5);
            %
            app.UIAxes2.XLim=app.UIAxes1.XLim;
            plot(app.UIAxes2,app.Plot1Data(:,2),app.Plot1Data(:,8),'LineWidth',1.5);
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            freeports = seriallist;
            app.PortDropDown.Items=freeports;
            app.UIAxes1.XLim=[-10 0];%
            app.UIAxes1.YLim=[0 500];%
            app.UIAxes2.XLim=[-10 0];%
            app.UIAxes2.YLim=[0 100];%
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            freeports = seriallist;
            app.PortDropDown.Items=freeports;
            app.COMPortName = app.PortDropDown.Value;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if strcmp(app.ConnectButton.Text,'Connect')
                app.COMBaudRate=app.BaudDropDown.Value;
                app.COMSerialPort=serial(app.COMPortName, 'TimeOut', 1,'BaudRate', str2num(app.BaudDropDown.Value),'Terminator','CR/LF' );
                s=instrfind('Port',app.COMPortName);
                if find(ismember(s.Status,'open'))
                    fclose(instrfind(s));
                end
                
                if strcmp(app.COMSerialPort.Status,'closed')
                    try(fopen(app.COMSerialPort));
                        fprintf(['port ' app.COMPortName ' opened\n'])
                        app.KpEditField.Enable = 'on';
                        app.KiEditField.Enable = 'on';
                        app.KdEditField.Enable = 'on';
                        app.SendButton.Enable = 'on';
                        app.MessageBoxTextArea.Enable = 'on';
                        app.ConnectButton.Text='Disconnect';
                        app.StartCaptureButton.Enable= 'on';
                    catch
                        fprintf(['port ' app.COMPortName ' not available\n'])
                    end
                else
                    fclose(app.COMSerialPort);
                end
            else
                app.KpEditField.Enable = 'off';
                app.KiEditField.Enable = 'off';
                app.KdEditField.Enable = 'off';
                app.SendButton.Enable = 'off';
                app.MessageBoxTextArea.Enable = 'off';
                app.ConnectButton.Text='Connect';
                app.StartCaptureButton.Enable= 'off';
                fclose(app.COMSerialPort);
                fprintf(['port ' app.COMPortName ' closed\n'])
            end
            
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            Kp=app.KpEditField.Value;
            %Kp_editboxValidFlag=handles.Kp_editboxValidFlag;
            KpData=strcat("Kp=", num2str(Kp,'%.2f'),",");
            %Ki
            Ki=app.KiEditField.Value;
            KiData=strcat("Ki=", num2str(Ki,'%.2f'),",");
            %Kd
            Kd=app.KdEditField.Value;
            KdData=strcat("Kd=", num2str(Kd,'%.2f'));
            TxData=strcat("START: ",KpData, " ", KiData," ", KdData, " :END");
            if strcmp(app.COMSerialPort.Status,'open')
                fprintf(app.COMSerialPort,TxData);
                app.MessageBoxTextArea.Value=TxData;
            else
                app.MessageBoxTextArea.Value="No opened COM port is existing";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            s=instrfind('Port',app.COMPortName);
            if find(ismember(s.Status,'open'))
                fclose(instrfind(s));
            end
            delete(app)
        end

        % Button pushed function: StartCaptureButton
        function StartCaptureButtonPushed(app, event)
            if strcmp(app.StartCaptureButton.Text,'Start Capture')
                app.StartCaptureButton.Text='Stop Capture';
                app.COMSerialPort.BytesAvailableFcnMode ='terminator';
                app.COMSerialPort.Terminator='CR/LF';
                app.FirstData=1;
                flushinput(app.COMSerialPort);
                app.COMSerialPort.BytesAvailableFcn = {@app.UARTReceiveCallback};
            else
                app.StartCaptureButton.Text='Start Capture';
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.BaudDropDownLabel = uilabel(app.ConnectionPanel);
            app.BaudDropDownLabel.Text = 'Baud';
            app.BaudDropDown = uidropdown(app.ConnectionPanel);
            app.BaudDropDown.Items = {'9600', '115200'};
            app.BaudDropDown.Value = '115200';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PortDropDownLabel = uilabel(app.ConnectionPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.ConnectionPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = {};
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Temperature')
            xlabel(app.UIAxes1, 't[s]')
            ylabel(app.UIAxes1, 'T[°C]')
            app.PIDtunePanel = uipanel(app.UIFigure);
            app.PIDtunePanel.Title = 'PID tune';
            app.SendButton = uibutton(app.PIDtunePanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.KpEditField_2Label = uilabel(app.PIDtunePanel);
            app.KpEditField_2Label.Enable = 'off';
            app.KpEditField_2Label.Text = 'Kp';
            app.KpEditField = uieditfield(app.PIDtunePanel, 'numeric');
            app.KpEditField.Limits = [0 9.99];
            app.KpEditField.Enable = 'off';
            app.KiEditFieldLabel = uilabel(app.PIDtunePanel);
            app.KiEditFieldLabel.Enable = 'off';
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.PIDtunePanel, 'numeric');
            app.KiEditField.Limits = [0 10];
            app.KiEditField.Enable = 'off';
            app.KdEditFieldLabel = uilabel(app.PIDtunePanel);
            app.KdEditFieldLabel.Enable = 'off';
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.PIDtunePanel, 'numeric');
            app.KdEditField.Limits = [0 10];
            app.KdEditField.Enable = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Duty')
            xlabel(app.UIAxes2, 't[s]')
            ylabel(app.UIAxes2, 'Duty[%]')
            app.UIAxes2.LineStyleOrder = {'- '};
            app.DataLoggingPanel = uipanel(app.UIFigure);
            app.DataLoggingPanel.Title = 'Data Logging';
            app.SamplesDropDownLabel = uilabel(app.DataLoggingPanel);
            app.SamplesDropDownLabel.Text = 'Samples';
            app.SamplesDropDown = uidropdown(app.DataLoggingPanel);
            app.SamplesDropDown.Items = {'100', '500', '1000'};
            app.SamplesDropDown.Value = '500';
            app.MessageBoxTextAreaLabel = uilabel(app.DataLoggingPanel);
            app.MessageBoxTextAreaLabel.Text = 'MessageBox';
            app.MessageBoxTextArea = uitextarea(app.DataLoggingPanel);
            app.MessageBoxTextArea.Editable = 'off';
            app.MessageBoxTextArea.Enable = 'off';
            app.StartCaptureButton = uibutton(app.DataLoggingPanel, 'push');
            app.StartCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @StartCaptureButtonPushed, true);
            app.StartCaptureButton.Enable = 'off';
            app.StartCaptureButton.Text = 'Start Capture';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SolderingIronApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end